classdef Strada < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        GridLayout2         matlab.ui.container.GridLayout
        SIMIALabel          matlab.ui.control.Label
        TabGroup            matlab.ui.container.TabGroup
        VideoAnalysisTab    matlab.ui.container.Tab
        GridLayout3         matlab.ui.container.GridLayout
        GridLayout6         matlab.ui.container.GridLayout
        Slider              matlab.ui.control.Slider
        GridLayout13        matlab.ui.container.GridLayout
        GridLayout15        matlab.ui.container.GridLayout
        FPSEditField        matlab.ui.control.NumericEditField
        FPSEditFieldLabel   matlab.ui.control.Label
        GridLayout14        matlab.ui.container.GridLayout
        StartSegmentButton  matlab.ui.control.Button
        PlayButton          matlab.ui.control.Button
        UIAxes              matlab.ui.control.UIAxes
        GridLayout4         matlab.ui.container.GridLayout
        SegmentsPanel       matlab.ui.container.Panel
        GridLayout7         matlab.ui.container.GridLayout
        UITable             matlab.ui.control.Table
        GridLayout9         matlab.ui.container.GridLayout
        GridLayout11        matlab.ui.container.GridLayout
        Label               matlab.ui.control.Label
        GridLayout12        matlab.ui.container.GridLayout
        ChangeButton        matlab.ui.control.Button
        DIRLabel            matlab.ui.control.Label
        GridLayout10        matlab.ui.container.GridLayout
        DropDown            matlab.ui.control.DropDown
        SaveSegmentsButton  matlab.ui.control.Button
        LabelsPanel         matlab.ui.container.Panel
        GridLayout8         matlab.ui.container.GridLayout
        selectButton_4      matlab.ui.control.Button
        selectButton_3      matlab.ui.control.Button
        selectButton_2      matlab.ui.control.Button
        selectButton        matlab.ui.control.Button
        EditField4          matlab.ui.control.EditField
        EditField3          matlab.ui.control.EditField
        EditField2          matlab.ui.control.EditField
        EditField           matlab.ui.control.EditField
        GridLayout5         matlab.ui.container.GridLayout
        SelectVideoButton   matlab.ui.control.Button
        KinematicsTab       matlab.ui.container.Tab
        GridLayout16        matlab.ui.container.GridLayout
        TabGroup2           matlab.ui.container.TabGroup
        allposTab           matlab.ui.container.Tab
        GridLayout17        matlab.ui.container.GridLayout
        GridLayout19        matlab.ui.container.GridLayout
        GridLayout21        matlab.ui.container.GridLayout
        Slider_2            matlab.ui.control.RangeSlider
        VelocityPanel       matlab.ui.container.Panel
        GridLayout23        matlab.ui.container.GridLayout
        UIAxesVel           matlab.ui.control.UIAxes
        PositionPanel       matlab.ui.container.Panel
        GridLayout22        matlab.ui.container.GridLayout
        UIAxesPos           matlab.ui.control.UIAxes
        GridLayout20        matlab.ui.container.GridLayout
        TextArea            matlab.ui.control.TextArea
        ListBox             matlab.ui.control.ListBox
        GridLayout18        matlab.ui.container.GridLayout
        SelectDLCallposition3DmatfileforstudyLabel  matlab.ui.control.Label
        LoadDLCButton       matlab.ui.control.Button
        Tab2                matlab.ui.container.Tab
    end

    
    properties (Access = public)
        Data        struct = struct     % Data
        Status      struct = struct     % App control
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % UI
            movegui(app.UIFigure, 'center')
            app = setDefaultUI(app);
            app = colorMe(app, 'DarkMode');
            % Data
            [app.Data, app.Status] = setProperties(app.Data, app.Status);
        end

        % Button pushed function: SelectVideoButton
        function SelectVideoButtonPushed(app, event)
            [file, path] = uigetfile({'*mp4'}, 'Select Video');
            % Back Focus
            figure(app.UIFigure);
            if path~= 0
                try
                    % reading video
                    vidObj = VideoReader([path, file]);
                    app.Data.beh.duration = vidObj.Duration;
                    app.Data.beh.vid_name = [app.Data.beh.vid_name, file];
                    app.Data.beh.default_FPS = vidObj.FrameRate;
                    app.Status.video_analysis.max_idx = vidObj.NumFrames;
                    app.Status.video_analysis.fps = vidObj.FrameRate;
                    app.Status.video_analysis.idx = 1;
                    
                    % set path as default path to save .mat table
                    app.Label.Text = path;
                    app.Status.video_analysis.dir_to_save = path;   % update

                    i = 1;
                    out = cell(2, vidObj.NumFrames);
                    % UIProgress
                    d = uiprogressdlg(app.UIFigure,'Title','Loading Video',...
                                     'Message', 'Insert joke here', 'Cancelable','on');
                    while hasFrame(vidObj)  % read all frames
                        d.Value = i / vidObj.NumFrames;
                        % d.Message = sprintf('%12.9f', )
                        if d.CancelRequested; break; end
                        out{1, i} = readFrame(vidObj);
                        out{2, i} = vidObj.CurrentTime;
                        i = i + 1;
                    end
                    % close uipgrogress
                    close(d)
                    if i ~= vidObj.NumFrames + 1
                        msg = 'Video load cancelled early';
                        uialert(app.UIFigure, msg, 'Warning', 'Icon','warning', 'Modal',true);
                        return
                    end
                    % save to public property
                    app.Data.beh.vid = out;

                    % showing first frame
                    cla(app.UIAxes)
                    imshow(out{1}, 'Parent', app.UIAxes);
                    axis(app.UIAxes, 'image')
                    app.UIAxes.Visible = 'on';

                    % enabling / disabling
                    app.PlayButton.Enable = 'on';
                    app.StartSegmentButton.Enable = 'on';
                    app.FPSEditField.Value = floor(vidObj.FrameRate);
                    app.Slider.Limits = [1, vidObj.NumFrames];
                    app.Slider.Enable = 'on';
                catch
                    % rese axis
                    cla(app.UIAxes)
                    % something went wrong
                    msg = 'Error reading video. Try again';
                    uialert(app.UIFigure, msg, 'Warning', 'Icon','warning', 'Modal',true);
                    % reset
                    startupFcn(app)
                end
            else
                % video not selected
                msg = 'No new video selected. Try again';
                uialert(app.UIFigure, msg, 'Warning', 'Icon','warning', 'Modal',true);
            end
        end

        % Button pushed function: ChangeButton
        function ChangeButtonPushed(app, event)
            new_path = uigetdir("Select Folder to Save");
            % Back Focus
            figure(app.UIFigure);
            if new_path ~= 0
                app.Label.Text = new_path;
                app.Status.video_analysis.dir_to_save = new_path;
            end
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            if strcmp(event.Source.Text, 'Play')
                if app.Slider.Value == app.Status.video_analysis.max_idx
                    app.Slider.Value = 1;
                end
                app.Status.video_analysis.play = true;
                app.PlayButton.Text = 'Stop';
            else
                app.Status.video_analysis.play = false;
                app.PlayButton.Text = 'Play';
            end

            % play video
            while app.Status.video_analysis.play
                % read frame given from the slider
                app.Status.video_analysis.idx = round(app.Slider.Value);
                imshow(app.Data.beh.vid{1, app.Status.video_analysis.idx}, 'Parent', app.UIAxes);
                pause(1/app.FPSEditField.Value);
                % check if ending
                if app.Status.video_analysis.idx == app.Status.video_analysis.max_idx
                    app.Status.video_analysis.play = false;
                    app.PlayButton.Text = 'Play';
                else
                    app.Slider.Value = app.Status.video_analysis.idx + 1;
                end
            end
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            app.Status.video_analysis.idx = round(value);
            % show preview image if video not already playing
            if ~app.Status.video_analysis.play
                imshow(app.Data.beh.vid{1, app.Status.video_analysis.idx}, 'Parent', app.UIAxes);
            end
        end

        % Button pushed function: StartSegmentButton
        function StartSegmentButtonPushed(app, event)
            % start segment for labeling
            if strcmp(event.Source.Text, 'Start Segment')
                app.Status.video_analysis.start_segment = app.Status.video_analysis.idx;
                app.StartSegmentButton.Text = 'Stop Segment';
            else
                % end of segment
                app.Status.video_analysis.end_segment = app.Status.video_analysis.idx;
                if app.Status.video_analysis.play
                    % end video play if running
                    app.Status.video_analysis.play = false;
                    app.PlayButton.Text = 'Play';
                end
                % time to choose what to label segment
                app.selectButton.Enable = 'on';
                app.selectButton_2.Enable = 'on';
                app.selectButton_3.Enable = 'on';
                app.selectButton_4.Enable = 'on';
                % change back name of button + disable it
                app.StartSegmentButton.Text = 'Start Segment';
                app.StartSegmentButton.Enable = 'off';
            end
        end

        % Button pushed function: selectButton, selectButton_2, 
        % ...and 2 other components
        function selectButtonPushed(app, event)
            % find what label it was
            switch event.Source.Tag
                case 'first'
                    lbl = app.EditField.Value;
                case 'second'
                    lbl = app.EditField2.Value;
                case 'third'
                    lbl = app.EditField3.Value;
                case 'fourth'
                    lbl = app.EditField4.Value;
            end
            % add to table
            current_t = app.UITable.Data;
            new_row = {lbl, app.Status.video_analysis.start_segment, app.Status.video_analysis.end_segment, ...
                            app.Data.beh.vid{2, app.Status.video_analysis.start_segment}, ...
                            app.Data.beh.vid{2, app.Status.video_analysis.end_segment}};
            app.UITable.Data = [current_t;new_row];
            % re-enable the buttons
            app.selectButton.Enable = 'off';
            app.selectButton_2.Enable = 'off';
            app.selectButton_3.Enable = 'off';
            app.selectButton_4.Enable = 'off';
            app.StartSegmentButton.Enable = 'on';
        end

        % Double-clicked callback: UITable
        function UITableDoubleClicked(app, event)
            displayRow = event.InteractionInformation.DisplayRow;
            % displayColumn = event.InteractionInformation.DisplayColumn;
            app.UITable.Data(displayRow, :) = [];
        end

        % Button pushed function: SaveSegmentsButton
        function SaveSegmentsButtonPushed(app, event)
            % get data
            t_to_save = app.UITable.Data;
            path_to_save = app.Status.video_analysis.dir_to_save;

            title = 'Saving Segments';
            msg = sprintf(['Creating file in %s\n' ...
                           'Number of Segments: %d'], ...
                           path_to_save, ...
                           size(app.UITable.Data, 1));

            selection = uiconfirm(app.UIFigure, msg, title, 'Options', ...
                                 {'Save', 'Cancel'}, ...
                                 'DefaultOption', 1, 'CancelOption', 2);

            if strcmp(selection, 'Save')
                save([app.Data.beh.vid_name, '.mat'], 't_to_save');
            elseif strcmp(selection, 'Cancel')
                return
            end
        end

        % Button pushed function: LoadDLCButton
        function LoadDLCButtonPushed(app, event)
            [file, path] = uigetfile({'*mat'}, 'Select .mat file');
            % Back Focus
            figure(app.UIFigure);
            if path~= 0
                try
                    data = load([path, file]);
                    [roi_num, xyz, frames] = size(data.all_pos3d);

                    % set up listbox
                    app.ListBox.Items  = arrayfun(@(x) ['ROI - ' num2str(x)], 1:roi_num, 'UniformOutput',false);

                    % stacked position plot
                    roi = 1;
                    data_roi = data.all_pos3d(roi, :,:);

                    C = permute(data_roi,[1,3,2]);                         % fix shape
                    C = reshape(C, [], size(C,2),1)';
                    offset_pos = max(C(:)) * (0:xyz-1)';                   % get offset
                    pos_data = zeros(frames, xyz);
                    for pos = 1:xyz
                        pos_data(:,pos) = C(:, pos) + offset_pos(pos);
                    end
                    plot(1:frames, pos_data, 'Parent', app.UIAxesPos);     % plot
                    line_names = {'x', 'y', 'z'};                          % labeling the y-axis
                    x_lines = zeros(1, xyz);
                    y_lines = pos_data(1, 1:xyz);
                    text(x_lines, y_lines, line_names, 'Color', 'w', 'Parent', app.UIAxesPos)   % plotting the label with text()
                    set(app.UIAxesPos, 'Color', 'k', 'Xcolor', 'w', 'YTick', [], 'Ycolor', 'w') 
                    set(get(app.UIAxesPos, 'ylabel'), 'string', 'Dimension')
                    
                    % visibilities
                    app.PositionPanel.Visible = 'on';
                catch
                    cla(app.UIAxesVel)
                    cla(app.UIAxesPos)
                    % something went wrong
                    msg = 'Error reading dlc file. Make sure it is the rec#.dlcall_pos3d.mat file!';
                    uialert(app.UIFigure, msg, 'Warning', 'Icon','warning', 'Modal',true);
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.VideoAnalysisTab = uitab(app.TabGroup);
            app.VideoAnalysisTab.Title = 'Video Analysis';
            app.GridLayout3 = uigridlayout(app.VideoAnalysisTab);
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.SelectVideoButton = uibutton(app.GridLayout5, 'push');
            app.SelectVideoButton.ButtonPushedFcn = createCallbackFcn(app, @SelectVideoButtonPushed, true);
            app.SelectVideoButton.Text = 'Select Video';
            app.LabelsPanel = uipanel(app.GridLayout4);
            app.LabelsPanel.Title = 'Labels';
            app.GridLayout8 = uigridlayout(app.LabelsPanel);
            app.EditField = uieditfield(app.GridLayout8, 'text');
            app.EditField2 = uieditfield(app.GridLayout8, 'text');
            app.EditField3 = uieditfield(app.GridLayout8, 'text');
            app.EditField4 = uieditfield(app.GridLayout8, 'text');
            app.selectButton = uibutton(app.GridLayout8, 'push');
            app.selectButton.ButtonPushedFcn = createCallbackFcn(app, @selectButtonPushed, true);
            app.selectButton.Tag = 'first';
            app.selectButton.Text = 'select';
            app.selectButton_2 = uibutton(app.GridLayout8, 'push');
            app.selectButton_2.ButtonPushedFcn = createCallbackFcn(app, @selectButtonPushed, true);
            app.selectButton_2.Tag = 'second';
            app.selectButton_2.Text = 'select';
            app.selectButton_3 = uibutton(app.GridLayout8, 'push');
            app.selectButton_3.ButtonPushedFcn = createCallbackFcn(app, @selectButtonPushed, true);
            app.selectButton_3.Tag = 'third';
            app.selectButton_3.Text = 'select';
            app.selectButton_4 = uibutton(app.GridLayout8, 'push');
            app.selectButton_4.ButtonPushedFcn = createCallbackFcn(app, @selectButtonPushed, true);
            app.selectButton_4.Tag = 'fourth';
            app.selectButton_4.Text = 'select';
            app.GridLayout9 = uigridlayout(app.GridLayout4);
            app.GridLayout10 = uigridlayout(app.GridLayout9);
            app.SaveSegmentsButton = uibutton(app.GridLayout10, 'push');
            app.SaveSegmentsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSegmentsButtonPushed, true);
            app.SaveSegmentsButton.Text = 'Save Segments';
            app.DropDown = uidropdown(app.GridLayout10);
            app.DropDown.Items = {'.mat'};
            app.DropDown.Value = '.mat';
            app.GridLayout11 = uigridlayout(app.GridLayout9);
            app.GridLayout12 = uigridlayout(app.GridLayout11);
            app.DIRLabel = uilabel(app.GridLayout12);
            app.DIRLabel.Text = 'DIR:';
            app.ChangeButton = uibutton(app.GridLayout12, 'push');
            app.ChangeButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeButtonPushed, true);
            app.ChangeButton.Text = 'Change';
            app.Label = uilabel(app.GridLayout11);
            app.SegmentsPanel = uipanel(app.GridLayout4);
            app.SegmentsPanel.Title = 'Segments';
            app.GridLayout7 = uigridlayout(app.SegmentsPanel);
            app.UITable = uitable(app.GridLayout7);
            app.UITable.ColumnName = {'Label'; 'Start (Frame)'; 'End (Frame)'; 'Start (sec)'; 'End (sec)'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [false true true true true];
            app.UITable.SelectionType = 'row';
            app.UITable.DoubleClickedFcn = createCallbackFcn(app, @UITableDoubleClicked, true);
            app.GridLayout6 = uigridlayout(app.GridLayout3);
            app.UIAxes = uiaxes(app.GridLayout6);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.GridLayout13 = uigridlayout(app.GridLayout6);
            app.GridLayout14 = uigridlayout(app.GridLayout13);
            app.PlayButton = uibutton(app.GridLayout14, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.StartSegmentButton = uibutton(app.GridLayout14, 'push');
            app.StartSegmentButton.ButtonPushedFcn = createCallbackFcn(app, @StartSegmentButtonPushed, true);
            app.StartSegmentButton.Text = 'Start Segment';
            app.GridLayout15 = uigridlayout(app.GridLayout13);
            app.FPSEditFieldLabel = uilabel(app.GridLayout15);
            app.FPSEditFieldLabel.Text = 'FPS';
            app.FPSEditField = uieditfield(app.GridLayout15, 'numeric');
            app.FPSEditField.Limits = [0 100];
            app.Slider = uislider(app.GridLayout6);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.KinematicsTab = uitab(app.TabGroup);
            app.KinematicsTab.Title = 'Kinematics';
            app.GridLayout16 = uigridlayout(app.KinematicsTab);
            app.TabGroup2 = uitabgroup(app.GridLayout16);
            app.allposTab = uitab(app.TabGroup2);
            app.allposTab.Title = 'all pos';
            app.GridLayout17 = uigridlayout(app.allposTab);
            app.GridLayout18 = uigridlayout(app.GridLayout17);
            app.LoadDLCButton = uibutton(app.GridLayout18, 'push');
            app.LoadDLCButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDLCButtonPushed, true);
            app.LoadDLCButton.Text = 'Load DLC';
            app.SelectDLCallposition3DmatfileforstudyLabel = uilabel(app.GridLayout18);
            app.SelectDLCallposition3DmatfileforstudyLabel.Text = 'Select DLC all position 3D .mat file for study';
            app.GridLayout19 = uigridlayout(app.GridLayout17);
            app.GridLayout20 = uigridlayout(app.GridLayout19);
            app.ListBox = uilistbox(app.GridLayout20);
            app.TextArea = uitextarea(app.GridLayout20);
            app.GridLayout21 = uigridlayout(app.GridLayout19);
            app.PositionPanel = uipanel(app.GridLayout21);
            app.PositionPanel.Title = 'Position';
            app.GridLayout22 = uigridlayout(app.PositionPanel);
            app.UIAxesPos = uiaxes(app.GridLayout22);
            app.UIAxesPos.XTick = [];
            app.UIAxesPos.XTickLabel = '';
            app.UIAxesPos.YTick = [];
            app.VelocityPanel = uipanel(app.GridLayout21);
            app.VelocityPanel.Title = 'Velocity';
            app.GridLayout23 = uigridlayout(app.VelocityPanel);
            app.UIAxesVel = uiaxes(app.GridLayout23);
            app.UIAxesVel.XTick = [];
            app.UIAxesVel.YTick = [];
            app.Slider_2 = uislider(app.GridLayout21, 'range');
            app.Tab2 = uitab(app.TabGroup2);
            app.Tab2.Title = 'Tab2';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.SIMIALabel = uilabel(app.GridLayout2);
            app.SIMIALabel.Text = 'SIMIA';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Strada

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end